classdef CFD_tool_withgrid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CFDToolUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadClassFileMenu               matlab.ui.container.Menu
        LoadNonClassFileMenu            matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        FilePreviewMenu                 matlab.ui.container.Menu
        GridLayout3                     matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.EditField
        PythonEnvButton                 matlab.ui.control.Button
        XTimesCuttoffEditField          matlab.ui.control.NumericEditField
        XTimesCuttoffLabel              matlab.ui.control.Label
        NonclassFileDropDown            matlab.ui.control.DropDown
        NonclassFileDropDownLabel       matlab.ui.control.Label
        ClassFileDropDownLabel          matlab.ui.control.Label
        ClassFileDropDown               matlab.ui.control.DropDown
        MissingVCButton                 matlab.ui.control.Button
        DownloadButton                  matlab.ui.control.Button
        NewDataButton_2                 matlab.ui.control.Button
        MainTabGroup                    matlab.ui.container.TabGroup
        PreviewTab                      matlab.ui.container.Tab
        PreviewTabGroup                 matlab.ui.container.TabGroup
        ClassFilePreviewTab             matlab.ui.container.Tab
        ClassPreviewTable               matlab.ui.control.Table
        NonClassFilePreviewTab          matlab.ui.container.Tab
        NonClassPreviewTable            matlab.ui.control.Table
        NVCTab                          matlab.ui.container.Tab
        InfoTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        PieChartTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        NonClassPanel                   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ClassPanel                      matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        VennDiagramTab                  matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        NotableValueCombinationsPanel   matlab.ui.container.Panel
        AllValueCombinationsPanel       matlab.ui.container.Panel
        BarGraphTab                     matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        BarAxes                         matlab.ui.control.UIAxes
        LinePlotsTab                    matlab.ui.container.Tab
        LineTabGroup                    matlab.ui.container.TabGroup
        AllTab                          matlab.ui.container.Tab
        Notable2Tab                     matlab.ui.container.Tab
        Notable3Tab                     matlab.ui.container.Tab
        LoadingLabel                    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        DisplaySettingsPanel            matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        Notable4WayCombinationsCheckBox  matlab.ui.control.CheckBox
        LineFreqencyButton              matlab.ui.control.Button
        VennButton                      matlab.ui.control.Button
        VarBarButton                    matlab.ui.control.Button
        VarCompPieButton                matlab.ui.control.Button
        VerboseOutputCheckBox           matlab.ui.control.CheckBox
        Notable3WayCombinationsCheckBox  matlab.ui.control.CheckBox
        Notable2WayCombinationsCheckBox  matlab.ui.control.CheckBox
        Unique1WayCombinationsCheckBox  matlab.ui.control.CheckBox
        OpenUnclassFile                 matlab.ui.control.Button
        OpenClassFile                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Classfolder
        UnClassfolder
        TableDashboard
        list
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function: LoadClassFileMenu, OpenClassFile
        function OpenClassFileButtonPushed(app, event)
            [filename, app.Classfolder] = uigetfile(fullfile(pwd,'*.csv'));
            if ~ischar(filename); return; end   %user cancel
            focus(app.CFDToolUIFigure)
            t = readtable(fullfile(app.Classfolder,filename));
            app.ClassPreviewTable.Data = t;
            app.ClassPreviewTable.ColumnName = t.Properties.VariableNames;
            
            [~,~,ext] = fileparts(filename);
            files = dir(fullfile(app.Classfolder,['*' ext]));
            app.ClassFileDropDown.Items = {files.name};
            app.ClassFileDropDown.Value = filename;
            app.ClassFileDropDown.Enable = 'on';

            app.ListBox.Items = app.ClassPreviewTable.ColumnName;

            if app.NonclassFileDropDown.Value ~= "Select File..."
                app.CalculateButton.Enable = "on";
            end

            app.MainTabGroup.SelectedTab = app.PreviewTab;
            app.PreviewTabGroup.SelectedTab = app.ClassFilePreviewTab;
        end

        % Callback function: LoadNonClassFileMenu, OpenUnclassFile
        function OpenUnclassFileButtonPushed(app, event)
            [filename, app.UnClassfolder] = uigetfile(fullfile(pwd,'*.csv'));
            if ~ischar(filename); return; end   %user cancel
            focus(app.CFDToolUIFigure)
            t = readtable(fullfile(app.Classfolder,filename));
            app.NonClassPreviewTable.Data = t;
            app.NonClassPreviewTable.ColumnName = t.Properties.VariableNames;
            
            [~,~,ext] = fileparts(filename);
            files = dir(fullfile(app.UnClassfolder,['*' ext]));
            app.NonclassFileDropDown.Items = {files.name};
            app.NonclassFileDropDown.Value = filename;
            app.NonclassFileDropDown.Enable = 'on';


            if app.ClassFileDropDown.Value ~= "Select File..."
                app.CalculateButton.Enable = "on";
            end

            app.MainTabGroup.SelectedTab = app.PreviewTab;
            app.PreviewTabGroup.SelectedTab = app.NonClassFilePreviewTab;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
                pyenv('Version', app.EditField.Value)

                % Get the current MATLAB path
                matlabPath = path;

                % Split the MATLAB path into individual directories
                matlabPathList = strsplit(matlabPath, pathsep);

                % Convert the MATLAB path list to a Python list
                pyPathList = py.list(matlabPathList);

                % Import the sys module in Python
                py.importlib.import_module('sys');

                % Set the Python path to match the MATLAB path
                % py.sys.path.clear();
                py.sys.path().extend(pyPathList);

                try
                    mod = py.importlib.import_module('Calc_v9'); % Make sure Calc_v9 is on path
                catch
                    msgbox(char(py.sys.path))
                end
                classFP = fullfile(app.Classfolder,app.ClassFileDropDown.Value);
                nonClassFP = fullfile(app.UnClassfolder,app.NonclassFileDropDown.Value);
                xcutoff = app.XTimesCuttoffEditField.Value;
                data = mod.process_files(classFP, nonClassFP, xcutoff);

                app.list = dictionary(data);

                check = app.list.lookup('canVerbose');
                app.VerboseOutputCheckBox.Enable = check{1};

                ok = app.list.lookup('grid');
                ok2 = string(ok{1});
                for ii = 1:3
                    yay(ii,:) = [ii str2num(ok2(ii))];
                end

                yay(:,9) = round(yay(:,9)*100,2);
                yay(:,end) = round(yay(:,end)*100,2);
                app.UITable.Data = yay;
                app.UITable.ColumnName = {'t','MaxVI','TCVI','TNVI','PCVI','PNVI','INT','NVC','% NVC','OCR','% OCR'};

                app.UITable.ColumnFormat = {'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'bank' 'numeric' 'bank'};

                app.Unique1WayCombinationsCheckBox.Enable = "on";
                app.Notable2WayCombinationsCheckBox.Enable = "on";
                app.Notable3WayCombinationsCheckBox.Enable = "on";
                app.LineFreqencyButton.Enable = "on";
                app.NewDataButton_2.Enable = "on";

                Unique1WayCombinationsCheckBoxValueChanged(app)
            catch ME
                msgbox( getReport( ME, 'extended', 'hyperlinks', 'on' ) )
            end
            
        end

        % Button pushed function: VarCompPieButton
        function VarCompPieButtonPushed(app, event)
            if  app.ListBox.Visible == "on"
                app.ListBox.Visible = "off";
            else
                app.ListBox.Visible = "on";
            end
          

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            % g = tiledlayout(app.PieChartTab,1,2);
            % nexttile(g)
            % piechart(g,app.ClassPreviewTable.Data{:,app.ListBox.ValueIndex})
            % nexttile(g)
            % piechart(g,app.NonClassPreviewTable.Data{:,app.ListBox.ValueIndex})


            piechart(app.ClassPanel,categorical(app.ClassPreviewTable.Data{:,app.ListBox.ValueIndex}))
            piechart(app.NonClassPanel,categorical(app.NonClassPreviewTable.Data{:,app.ListBox.ValueIndex}))


            % histogram(app.UIAxes_2,categorical(app.ClassPreviewTable.Data{:,app.ListBox.ValueIndex}))
            % histogram(app.UIAxes,categorical(app.NonClassPreviewTable.Data{:,app.ListBox.ValueIndex}))

            app.MainTabGroup.SelectedTab = app.PieChartTab;
        end

        % Value changed function: Notable2WayCombinationsCheckBox, 
        % ...and 2 other components
        function Unique1WayCombinationsCheckBoxValueChanged(app, event)
            value = [app.Unique1WayCombinationsCheckBox.Value, app.Notable2WayCombinationsCheckBox.Value, app.Notable3WayCombinationsCheckBox.Value];
            type = ["print1Ways", "print2Ways", "print3Ways"];
            columnName = ["1 Way Combination", "2 Way Combination", "3 Way Combination"];
            rows = app.Unique1WayCombinationsCheckBox.Value + app.Notable2WayCombinationsCheckBox.Value + app.Notable3WayCombinationsCheckBox.Value;

            if rows > 0
                app.TableDashboard = uigridlayout(app.NVCTab,[rows, 1]);
                for r = 1:3
                    if value(r) == 1
                        t = uitable(app.TableDashboard);
                        ok = app.list.lookup(type(r));
                        t.Data = string(ok{1})';
                        t.ColumnName = columnName(r);
                    end
                end
            end

            app.MainTabGroup.SelectedTab = app.NVCTab;

        end

        % Button pushed function: VennButton
        function VennButtonPushed(app, event)


            rowNames = ["1 way","2 way","3 way"];
            ColumnNames = ["Class","NonClass","Intersect"];


            % Notable Value Combinations
            ok = app.list.lookup("occurNotableLst");
            sample = strrep(string(ok{1})',"[","");
            sample = strrep(sample,"]","");
            m = str2num(char(sample));
            m = [0 12 1 ; 1 34 10 ; 7 0 59];

            tl = tiledlayout(app.NotableValueCombinationsPanel,3,1);
            for ii = 1:size(m,1)
                nexttile(tl)
                class = 1:m(ii,1)+m(ii,3);
                nonclass = m(ii,1)+1:sum(m(ii,:));
                % intersect(class,nonclass);

                setListData = {class,nonclass};
                setLabels = {m(ii,1),m(ii,2)};
                % {"Class","NonClass"};
                try
                    h = vennEulerDiagram(tl,setListData, setLabels, 'drawProportional', false);
                    h.ShowIntersectionAreas = true;
                    h.ShowIntersectionCounts = true;
                    h.SetLabels = [];
                catch
                    % annotation("textbox","Not Applicable")
                end
            end


            % All Value Combinations
            ok = app.list.lookup("grid");
            ok2 = cell(ok{1});

            for jj = 1:size(ok2,2)
                hmm = double(ok2{jj});
                m(jj,1) = hmm(4)-hmm(6);
                m(jj,2)  = hmm(5)-hmm(6);
                m(jj,3)  = hmm(6);
            end

            tl = tiledlayout(app.AllValueCombinationsPanel,3,1);
            for ii = 1:size(m,1)
                nexttile(tl)
                class = 1:m(ii,1)+m(ii,3);
                nonclass = m(ii,1)+1:sum(m(ii,:));
                % intersect(class,nonclass);

                setListData = {class,nonclass};
                setLabels = {m(ii,1),m(ii,2)};
                % {"Class","NonClass"};
                try
                    h = vennEulerDiagram(tl,setListData, setLabels, 'drawProportional', false);
                    h.ShowIntersectionAreas = true;
                    h.ShowIntersectionCounts = true;
                    h.SetLabels = [];
                catch
                    % annotation("textbox","Not Applicable")
                end
            end

            app.MainTabGroup.SelectedTab = app.VennDiagramTab;
        end

        % Button pushed function: VarBarButton
        function VarBarButtonPushed(app, event)
            ok = app.list.lookup("count1");
            m2(1,:) = double(ok{1});

            ok = app.list.lookup("count2");
            m2(2,:) = double(ok{1});

            ok = app.list.lookup("count3");
            m2(3,:) = double(ok{1});

            ok = app.list.lookup("variableNames");
            variablenames = string(ok{1});

            [value, index] = sort(sum(m2),"ascend");

            bar(app.BarAxes,variablenames(index),m2(:,index)','stacked')
            app.MainTabGroup.SelectedTab = app.BarGraphTab;
        end

        % Button pushed function: LineFreqencyButton
        function LineFreqencyButtonPushed(app, event)
            ok = app.list.lookup("lines1");
            ok2 = strrep(string(ok{1})',"[","");
            ok2 = strrep(ok2,"]","");
            m1 = array2table(str2num(char(ok2))');
            m1.Properties.VariableNames = ["Class","NonClass","Difference"];
            stackedplot(app.AllTab,m1,"XLabel","Combinations");

            app.MainTabGroup.SelectedTab = app.LinePlotsTab;
            app.LineTabGroup.SelectedTab = app.AllTab;

            ok = app.list.lookup("lines2");
            ok2 = strrep(string(ok{1})',"[","");
            ok2 = strrep(ok2,"]","");
            m1 = array2table(str2num(char(ok2))');
            m1.Properties.VariableNames = ["Class","NonClass","Difference"];
            stackedplot(app.Notable2Tab,m1,"XLabel","Combinations");

            ok = app.list.lookup("lines3");
            ok2 = strrep(string(ok{1})',"[","");
            ok2 = strrep(ok2,"]","");
            m1 = array2table(str2num(char(ok2))');
            m1.Properties.VariableNames = ["Class","NonClass","Difference"];
            stackedplot(app.Notable3Tab,m1,"XLabel","Combinations");
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            % uiputfile('*.txt')
            DownloadData2txtfile(app)

            focus(app.CFDToolUIFigure)
            uiwait(msgbox('Download Complete'))
            focus(app.CFDToolUIFigure)
        end

        % Button pushed function: MissingVCButton
        function MissingVCButtonPushed(app, event)
            DownloadMVC2txtfile(app)

            focus(app.CFDToolUIFigure)
            uiwait(msgbox('Download Complete'))
            focus(app.CFDToolUIFigure)
        end

        % Button pushed function: NewDataButton_2
        function NewDataButton_2Pushed(app, event)
            
           
            x = inputdlg(app.ClassPreviewTable.ColumnName);

            ok = app.list.lookup("notable_1way_combinations_nonclass");
            lastvalue_nonclass = string(ok{1});
            lastvalue_nonclass = lastvalue_nonclass';
            s_nonclass = string(ok{1});
            s_nonclass = s_nonclass';

            for ii = 1:height(s_nonclass)
                try
                    s_nonclass(ii) = extractBetween(s_nonclass(ii),'[''',''']');
                catch
                end
            end

             for ii = 1:height(lastvalue_nonclass)
                try
                    lastvalue_nonclass(ii) = extractBetween(lastvalue_nonclass(ii),', ''',''']');
                catch
                end
            end

            % one-way class
            ok = app.list.lookup("notable_1way_combinations_class");
            lastvalue_class = string(ok{1});
            lastvalue_class = lastvalue_class';
            s_class = string(ok{1});
            s_class = s_class';
            
            for ii = 1:height(s_class)
                try
                    s_class(ii) = extractBetween(s_class(ii),'[''',''']');
                catch
                end
            end

            for ii = 1:height(lastvalue_class)
                try
                    lastvalue_class(ii) = extractBetween(lastvalue_class(ii),', ''',''']');
                catch
                end
            end

            msgbox(["Evidence in favor of class:                                                  "+newline ;...
                string(app.ClassPreviewTable.ColumnName(x == s_class))+": "+string(x(x == s_class))+" and Freq Diff: "+ lastvalue_class(x == s_class);...
                newline+"Evidence in favor of nonclass: "+newline ;...
                string(app.ClassPreviewTable.ColumnName(x == s_nonclass))+": "+string(x(x == s_nonclass))+" and Freq Diff: "+ lastvalue_nonclass(x == s_nonclass)],...
                "Desination")

        end

        % Button pushed function: PythonEnvButton
        function PythonEnvButtonPushed(app, event)
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Choose the Python executable file...');

            if file
                try
                    Python = pyenv('Version', fullfile(pathfile, file));
                catch ME
                    msgbox(app, 'error', getReport(ME))
                end
            end

            app.EditField.Value = fullfile(pathfile,file);
            focus(app.CFDToolUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CFDToolUIFigure = uifigure('Visible', 'off');
            app.CFDToolUIFigure.Name = 'CFD Tool';
            app.FileMenu = uimenu(app.CFDToolUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadClassFileMenu = uimenu(app.FileMenu);
            app.LoadClassFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenClassFileButtonPushed, true);
            app.LoadClassFileMenu.Text = 'Load Class File...';
            app.LoadNonClassFileMenu = uimenu(app.FileMenu);
            app.LoadNonClassFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenUnclassFileButtonPushed, true);
            app.LoadNonClassFileMenu.Text = 'Load NonClassFile...';
            app.ViewMenu = uimenu(app.CFDToolUIFigure);
            app.ViewMenu.Text = 'View';
            app.FilePreviewMenu = uimenu(app.ViewMenu);
            app.FilePreviewMenu.Text = 'File Preview';
            app.GridLayout3 = uigridlayout(app.CFDToolUIFigure);
            app.OpenClassFile = uibutton(app.GridLayout3, 'push');
            app.OpenClassFile.ButtonPushedFcn = createCallbackFcn(app, @OpenClassFileButtonPushed, true);
            app.OpenClassFile.Icon = 'file_open.png';
            app.OpenClassFile.Tooltip = {'Load Class File'};
            app.OpenClassFile.Text = {''; ''};
            app.OpenUnclassFile = uibutton(app.GridLayout3, 'push');
            app.OpenUnclassFile.ButtonPushedFcn = createCallbackFcn(app, @OpenUnclassFileButtonPushed, true);
            app.OpenUnclassFile.Icon = 'file_open.png';
            app.OpenUnclassFile.Tooltip = {'Load Unclass File'};
            app.OpenUnclassFile.Text = {''; ''};
            app.DisplaySettingsPanel = uipanel(app.GridLayout3);
            app.DisplaySettingsPanel.Title = 'Display Settings';
            app.Unique1WayCombinationsCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.Unique1WayCombinationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Unique1WayCombinationsCheckBoxValueChanged, true);
            app.Unique1WayCombinationsCheckBox.Enable = 'off';
            app.Unique1WayCombinationsCheckBox.Text = 'Unique 1-Way Combinations';
            app.Unique1WayCombinationsCheckBox.Value = true;
            app.Notable2WayCombinationsCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.Notable2WayCombinationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Unique1WayCombinationsCheckBoxValueChanged, true);
            app.Notable2WayCombinationsCheckBox.Enable = 'off';
            app.Notable2WayCombinationsCheckBox.Text = 'Notable 2-Way Combinations';
            app.Notable3WayCombinationsCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.Notable3WayCombinationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Unique1WayCombinationsCheckBoxValueChanged, true);
            app.Notable3WayCombinationsCheckBox.Enable = 'off';
            app.Notable3WayCombinationsCheckBox.Text = 'Notable 3-Way Combinations';
            app.VerboseOutputCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.VerboseOutputCheckBox.Enable = 'off';
            app.VerboseOutputCheckBox.Text = 'Verbose Output';
            app.VarCompPieButton = uibutton(app.DisplaySettingsPanel, 'push');
            app.VarCompPieButton.ButtonPushedFcn = createCallbackFcn(app, @VarCompPieButtonPushed, true);
            app.VarCompPieButton.Text = 'Var Comp Pie';
            app.VarBarButton = uibutton(app.DisplaySettingsPanel, 'push');
            app.VarBarButton.ButtonPushedFcn = createCallbackFcn(app, @VarBarButtonPushed, true);
            app.VarBarButton.Text = 'Var Bar';
            app.VennButton = uibutton(app.DisplaySettingsPanel, 'push');
            app.VennButton.ButtonPushedFcn = createCallbackFcn(app, @VennButtonPushed, true);
            app.VennButton.Text = 'Venn';
            app.LineFreqencyButton = uibutton(app.DisplaySettingsPanel, 'push');
            app.LineFreqencyButton.ButtonPushedFcn = createCallbackFcn(app, @LineFreqencyButtonPushed, true);
            app.LineFreqencyButton.Enable = 'off';
            app.LineFreqencyButton.Text = 'Line Freqency';
            app.Notable4WayCombinationsCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.Notable4WayCombinationsCheckBox.Visible = 'off';
            app.Notable4WayCombinationsCheckBox.Text = 'Notable 4-Way Combinations';
            app.ListBox = uilistbox(app.DisplaySettingsPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Visible = 'off';
            app.CalculateButton = uibutton(app.GridLayout3, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.LoadingLabel = uilabel(app.GridLayout3);
            app.LoadingLabel.Visible = 'off';
            app.LoadingLabel.Text = 'Loading...';
            app.MainTabGroup = uitabgroup(app.GridLayout3);
            app.PreviewTab = uitab(app.MainTabGroup);
            app.PreviewTab.Title = 'Preview';
            app.PreviewTabGroup = uitabgroup(app.PreviewTab);
            app.ClassFilePreviewTab = uitab(app.PreviewTabGroup);
            app.ClassFilePreviewTab.Title = 'Class File';
            app.ClassPreviewTable = uitable(app.ClassFilePreviewTab);
            app.ClassPreviewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ClassPreviewTable.RowName = {};
            app.NonClassFilePreviewTab = uitab(app.PreviewTabGroup);
            app.NonClassFilePreviewTab.Title = 'NonClass File';
            app.NonClassPreviewTable = uitable(app.NonClassFilePreviewTab);
            app.NonClassPreviewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.NonClassPreviewTable.RowName = {};
            app.NVCTab = uitab(app.MainTabGroup);
            app.NVCTab.Title = 'NVC';
            app.InfoTab = uitab(app.MainTabGroup);
            app.InfoTab.Title = 'Info';
            app.GridLayout4 = uigridlayout(app.InfoTab);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PieChartTab = uitab(app.MainTabGroup);
            app.PieChartTab.Title = 'Pie Chart';
            app.GridLayout2 = uigridlayout(app.PieChartTab);
            app.ClassPanel = uipanel(app.GridLayout2);
            app.ClassPanel.Title = 'Class';
            app.UIAxes_2 = uiaxes(app.ClassPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.NonClassPanel = uipanel(app.GridLayout2);
            app.NonClassPanel.Title = 'NonClass';
            app.UIAxes = uiaxes(app.NonClassPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.VennDiagramTab = uitab(app.MainTabGroup);
            app.VennDiagramTab.Title = 'Venn Diagram';
            app.GridLayout = uigridlayout(app.VennDiagramTab);
            app.AllValueCombinationsPanel = uipanel(app.GridLayout);
            app.AllValueCombinationsPanel.Title = 'All Value Combinations';
            app.NotableValueCombinationsPanel = uipanel(app.GridLayout);
            app.NotableValueCombinationsPanel.Title = 'Notable Value Combinations';
            app.BarGraphTab = uitab(app.MainTabGroup);
            app.BarGraphTab.Title = 'Bar Graph';
            app.GridLayout5 = uigridlayout(app.BarGraphTab);
            app.BarAxes = uiaxes(app.GridLayout5);
            xlabel(app.BarAxes, 'Features')
            ylabel(app.BarAxes, 'Y')
            zlabel(app.BarAxes, 'Z')
            app.LinePlotsTab = uitab(app.MainTabGroup);
            app.LinePlotsTab.Title = 'Line Plots';
            app.LineTabGroup = uitabgroup(app.LinePlotsTab);
            app.AllTab = uitab(app.LineTabGroup);
            app.AllTab.Title = 'All';
            app.Notable2Tab = uitab(app.LineTabGroup);
            app.Notable2Tab.Title = 'Notable 2';
            app.Notable3Tab = uitab(app.LineTabGroup);
            app.Notable3Tab.Title = 'Notable 3';
            app.NewDataButton_2 = uibutton(app.GridLayout3, 'push');
            app.NewDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @NewDataButton_2Pushed, true);
            app.NewDataButton_2.Enable = 'off';
            app.NewDataButton_2.Text = 'New Data';
            app.DownloadButton = uibutton(app.GridLayout3, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.MissingVCButton = uibutton(app.GridLayout3, 'push');
            app.MissingVCButton.ButtonPushedFcn = createCallbackFcn(app, @MissingVCButtonPushed, true);
            app.MissingVCButton.Text = 'Missing VC';
            app.ClassFileDropDown = uidropdown(app.GridLayout3);
            app.ClassFileDropDown.Items = {'Select File...'};
            app.ClassFileDropDown.Enable = 'off';
            app.ClassFileDropDown.Value = 'Select File...';
            app.ClassFileDropDownLabel = uilabel(app.GridLayout3);
            app.ClassFileDropDownLabel.Text = 'Class File';
            app.NonclassFileDropDownLabel = uilabel(app.GridLayout3);
            app.NonclassFileDropDownLabel.Text = 'Nonclass File';
            app.NonclassFileDropDown = uidropdown(app.GridLayout3);
            app.NonclassFileDropDown.Items = {'Select File...'};
            app.NonclassFileDropDown.Enable = 'off';
            app.NonclassFileDropDown.Value = 'Select File...';
            app.XTimesCuttoffLabel = uilabel(app.GridLayout3);
            app.XTimesCuttoffLabel.Text = 'X Times Cuttoff:';
            app.XTimesCuttoffEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XTimesCuttoffEditField.Limits = [1 Inf];
            app.XTimesCuttoffEditField.Value = 10;
            app.PythonEnvButton = uibutton(app.GridLayout3, 'push');
            app.PythonEnvButton.ButtonPushedFcn = createCallbackFcn(app, @PythonEnvButtonPushed, true);
            app.PythonEnvButton.Text = 'Python Env';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            % Show the figure after all components are created
            app.CFDToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CFD_tool_withgrid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CFDToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CFDToolUIFigure)
        end
    end
end